classdef gonogoGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        RunTab                       matlab.ui.container.Tab
        CameraPanel                  matlab.ui.container.Panel
        ResetButton                  matlab.ui.control.Button
        SaveRecordingSwitch          matlab.ui.control.Switch
        SaveRecordingSwitchLabel     matlab.ui.control.Label
        ConnectCameraSwitch          matlab.ui.control.Switch
        ConnectCameraSwitchLabel     matlab.ui.control.Label
        AutoCheckBox                 matlab.ui.control.CheckBox
        ExposureSpinner              matlab.ui.control.Spinner
        ExposureSpinnerLabel         matlab.ui.control.Label
        FPSLabel                     matlab.ui.control.Label
        FramerateSpinner             matlab.ui.control.Spinner
        FramerateSpinnerLabel        matlab.ui.control.Label
        PreviewButton                matlab.ui.control.Button
        LoggingSettingsPanel         matlab.ui.container.Panel
        WeightEditField              matlab.ui.control.NumericEditField
        WeightEditFieldLabel         matlab.ui.control.Label
        MouseIDEditField             matlab.ui.control.NumericEditField
        MouseIDEditFieldLabel        matlab.ui.control.Label
        DAQDevicePanel               matlab.ui.container.Panel
        ChooseButton                 matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        ParametersTab                matlab.ui.container.Tab
        TrialsPanel                  matlab.ui.container.Panel
        TrialAmountEditField         matlab.ui.control.NumericEditField
        TrialAmountEditFieldLabel    matlab.ui.control.Label
        SoundsPanel                  matlab.ui.container.Panel
        NoGoSoundLabel               matlab.ui.control.Label
        GoSoundLabel                 matlab.ui.control.Label
        PickButton_2                 matlab.ui.control.Button
        PickButton                   matlab.ui.control.Button
        ThresholdsPanel              matlab.ui.container.Panel
        nogolickthresholdEditField   matlab.ui.control.NumericEditField
        nogolickthresholdEditFieldLabel  matlab.ui.control.Label
        lickthresholdEditField       matlab.ui.control.NumericEditField
        lickthresholdEditFieldLabel  matlab.ui.control.Label
        ConditionsPanel              matlab.ui.container.Panel
        Proto3Switch                 matlab.ui.control.Switch
        Proto3SwitchLabel            matlab.ui.control.Label
        Lamp_4                       matlab.ui.control.Lamp
        RewardCRSwitch               matlab.ui.control.Switch
        RewardCRSwitchLabel          matlab.ui.control.Label
        Lamp_3                       matlab.ui.control.Lamp
        Lamp_2                       matlab.ui.control.Lamp
        Lamp                         matlab.ui.control.Lamp
        CSduringCatchSwitch          matlab.ui.control.Switch
        CSduringCatchSwitchLabel     matlab.ui.control.Label
        airpuffSwitch                matlab.ui.control.Switch
        airpuffSwitchLabel           matlab.ui.control.Label
        ProbabilitiesPanel           matlab.ui.container.Panel
        CatchChancesEditField        matlab.ui.control.NumericEditField
        CatchChancesEditFieldLabel   matlab.ui.control.Label
        NoGoChancesEditField         matlab.ui.control.NumericEditField
        NoGoChancesEditFieldLabel    matlab.ui.control.Label
        GoChancesEditField           matlab.ui.control.NumericEditField
        GoChancesEditFieldLabel      matlab.ui.control.Label
        TimingssPanel                matlab.ui.container.Panel
        TOEditField                  matlab.ui.control.NumericEditField
        TOEditFieldLabel             matlab.ui.control.Label
        TRWEditField                 matlab.ui.control.NumericEditField
        TRWEditFieldLabel            matlab.ui.control.Label
        NLPEditField                 matlab.ui.control.NumericEditField
        NLPEditFieldLabel            matlab.ui.control.Label
        ITImaxEditField              matlab.ui.control.NumericEditField
        ITImaxEditFieldLabel         matlab.ui.control.Label
        ITIminEditField              matlab.ui.control.NumericEditField
        ITIminEditFieldLabel         matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        LiveMouseREACTIONLabel       matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        rewcount                     matlab.ui.control.NumericEditField
        RewardLabel                  matlab.ui.control.Label
        Hit                          matlab.ui.control.NumericEditField
        HitEditFieldLabel            matlab.ui.control.Label
        FACRLamp                     matlab.ui.control.Lamp
        FACRLampLabel                matlab.ui.control.Label
        HitMissLamp                  matlab.ui.control.Lamp
        HitMissLampLabel             matlab.ui.control.Label
        PauseButton                  matlab.ui.control.StateButton
        RepeatFACheckBox             matlab.ui.control.CheckBox
        SaveParametersButton         matlab.ui.control.Button
        LoadParametersButton         matlab.ui.control.Button
        GiveRewardButton             matlab.ui.control.StateButton
        STOPButton                   matlab.ui.control.StateButton
        RUNButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: airpuffSwitch
        function airpuffSwitchValueChanged(app, event)
            value = app.airpuffSwitch.Value;
            
        end

        % Value changed function: CSduringCatchSwitch
        function CSduringCatchSwitchValueChanged(app, event)
            value = app.CSduringCatchSwitch.Value;
            
        end

        % Value changed function: RewardCRSwitch
        function RewardCRSwitchValueChanged(app, event)
            value = app.RewardCRSwitch.Value;
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
                        % signage button
            app.RUNButton.BackgroundColor = 'g';
            app.STOPButton.BackgroundColor = [0.96,0.96,0.96];
            app.RUNButton.Enable = 'off';
            drawnow
            
            % Path definition
            [app.current_path, ~] = fileparts(mfilename('fullpath'));
            
            % Initialize parameters, sounds, video, and protocol
            if app.PreviewButton.Value
                app.PreviewButton.Value = 0;
                app.PreviewButton.BackgroundColor = [0.96,0.96,0.96];
                delete(app.VideoAcq)
                clear app.VideoAcq
            end

            initialize(app)

            if contains(app.NICard_filename.Value,'setup_for_sound_calibration')
        
                disp('Calibrating, be patient')
                [path, ~] = fileparts(app.Sound_filename.Value);
    
                Savepath = [path '\Calib\' date filesep];
                if ~exist(Savepath, 'dir')
                    mkdir(Savepath)
                end
    
                calibrate_dB(app.Sound_filename.Value, app.L.Value, app.user_data.sRec, app.user_data.stim, app.fsound.Value,app.frec.Value, Savepath);

            elseif contains(app.NICard_filename.Value, 'setup_for_reward_calibration')
                disp('Calibrating of water valves, be patient')
                calibrate_reward(app);
            else

                % Create result folder based on current time
                [h,m,s] = hms(datetime);
                folder_name = [num2str(h, '%02d') num2str(m, '%02d') num2str(round(s), '%02d') '_Data'];
                app.ExpFolder = [app.SavePath filesep folder_name];
                mkdir(app.ExpFolder)
    
                app.TextArea.Value{9} = ['This experiment started at ' num2str(h, '%02d') 'h ' num2str(m, '%02d') 'min ' num2str(round(s), '%02d') 's'];
    
                % Only port in.
                datlist_in={'TTLSound','SoundCopy','Piezo','Lick','micro',...
                'TTLtrig2ph','TTLtrigcamera','TTLtrigsounds','TTLReward','TTLReward2',...
                'TTLstartcam','airpuff','airpuff2'};
                legend_info = [];
                data_struct = struct();
                for i = 1:numel(app.user_data.sRec.Channels)
                    port_num = str2double(app.user_data.sRec.Channels(i).Name(end));
                    for n=1:length(datlist_in)
                        if isfield(app.user_data, datlist_in{n})
                            data_struct.([datlist_in{n} 'Data']) = fopen([app.ExpFolder filesep datlist_in{n} '.bin'],'w');
                            if port_num == app.user_data.(datlist_in{n})
                                legend_info{i, 1} = datlist_in{n};
                                legend_info{i, 2} = port_num;
                            end
                        end
                    end
                end
    
                data_struct.TrialData = fopen([app.ExpFolder filesep 'TrialType.bin'],'w');
                data_struct.WhichSoundData = fopen([app.ExpFolder filesep 'WhichSound.bin'],'w');
    
                % Plot parameters
                capture.TimeSpan = 10;
                capture.plotTimeSpan = 10;
                callbackTimeSpan = double(app.user_data.sRec.ScansAvailableFcnCount)/app.user_data.sRec.Rate;
                capture.bufferTimeSpan = max([capture.plotTimeSpan, capture.TimeSpan * 3, callbackTimeSpan * 3]);
                capture.bufferSize = round(capture.bufferTimeSpan * app.user_data.sRec.Rate);
    
                app.LivePlot = plot(app.UIAxes, 0, zeros(1, numel(app.user_data.sRec.Channels)));
                title(app.UIAxes, 'Online record','color','w');
                app.PerformancePlot = plot(app.UIAxes2, 0, zeros(1, 2));
                app.LickCount = plot(app.UIAxes3, 0, zeros(1, 2));
                
                %% Initialisation of the coeff plots
                if app.TwophCheckBox.Value
                    app.Ch1.Value = 1; app.Ch2.Value = 0.2; app.Ch3.Value = 0.2; app.Ch4.Value = 0.2;
                else 
                    app.Ch1.Value = 1/6; app.Ch2.Value = 4; app.Ch3.Value = 10; app.Ch4.Value = 2; app.Ch5.Value = 2;
                end

                %% Main acquisition pipeline
                envoi_stim(app, data_struct, capture, legend_info);
    
                %% Save data          
                fclose('all');
                save_app_data_tmp(app);

                %% Convert video from .mat to .avi
                cd(app.ExpFolder)
                if app.ConnectCameraCheckBox.Value && app.AutoCheckBox.Value && app.SaveRecordingCheckBox.Value
                    disp('converting video file...')
                    convert_mat_to_avi(app.CameraFR.Value)
                    disp('...done')
                end
    
                %% SAVING DATA both locally and on Gaïa 
                disp('Now saving data to Gaia....')
                Path_to_copy = [app.current_path filesep 'Results' filesep app.UserName.Value filesep 'M' num2str(app.MouseId.Value) filesep num2str(yyyymmdd(datetime)) filesep folder_name];
                mkdir(Path_to_copy)
    
                data_in_folder = dir;
                for i = 1:length(data_in_folder)
                    if contains(data_in_folder(i).name,{'.bin','.dat', 'StimId', '.csv', '.png'})
                        copyfile([app.ExpFolder filesep data_in_folder(i).name],[Path_to_copy filesep data_in_folder(i).name]);
                    end
                end
    
                if app.ConnectCameraCheckBox.Value && app.AutoCheckBox.Value && app.SaveRecordingCheckBox.Value
                    copyfile([app.ExpFolder filesep 'MouseVideo.avi'],[Path_to_copy filesep 'MouseVideo.avi']);
                    copyfile([app.ExpFolder filesep 'VideoTiming.mat'],[Path_to_copy filesep 'VideoTiming.mat']);
                end
            
            end
            disp('...done')

            %% Reset app
            daqreset
            app.STOPButton.Value = 0;
            app.RUNButton.BackgroundColor = [0.96,0.96,0.96];
            app.STOPButton.BackgroundColor = [0.96,0.96,0.96];
            drawnow
            app.RUNButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.RUNButton = uibutton(app.Panel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.STOPButton = uibutton(app.Panel, 'state');
            app.STOPButton.Text = 'STOP';
            app.GiveRewardButton = uibutton(app.Panel, 'state');
            app.GiveRewardButton.Text = 'Give Reward';
            app.LoadParametersButton = uibutton(app.Panel, 'push');
            app.LoadParametersButton.Text = 'Load Parameters';
            app.SaveParametersButton = uibutton(app.Panel, 'push');
            app.SaveParametersButton.Text = 'Save Parameters';
            app.RepeatFACheckBox = uicheckbox(app.Panel);
            app.RepeatFACheckBox.Text = 'Repeat FA?';
            app.PauseButton = uibutton(app.Panel, 'state');
            app.PauseButton.Text = 'Pause';
            app.HitMissLampLabel = uilabel(app.Panel);
            app.HitMissLampLabel.Text = 'Hit / Miss';
            app.HitMissLamp = uilamp(app.Panel);
            app.FACRLampLabel = uilabel(app.Panel);
            app.FACRLampLabel.Text = 'FA / CR';
            app.FACRLamp = uilamp(app.Panel);
            app.HitEditFieldLabel = uilabel(app.Panel);
            app.HitEditFieldLabel.Text = '# Hit';
            app.Hit = uieditfield(app.Panel, 'numeric');
            app.RewardLabel = uilabel(app.Panel);
            app.RewardLabel.Text = '# Reward';
            app.rewcount = uieditfield(app.Panel, 'numeric');
            app.Image = uiimage(app.Panel);
            app.LiveMouseREACTIONLabel = uilabel(app.Panel);
            app.LiveMouseREACTIONLabel.Text = 'Live Mouse REACTION!';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.UIAxes = uiaxes(app.RunTab);
            title(app.UIAxes, 'Learning Curve')
            xlabel(app.UIAxes, 'Trial nb')
            ylabel(app.UIAxes, 'lick count')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RunTab);
            title(app.UIAxes_2, 'Lick count')
            xlabel(app.UIAxes_2, 'Trial nb')
            ylabel(app.UIAxes_2, 'lick count')
            zlabel(app.UIAxes_2, 'Z')
            app.DAQDevicePanel = uipanel(app.RunTab);
            app.DAQDevicePanel.Title = 'DAQ Device';
            app.ChooseButton = uibutton(app.DAQDevicePanel, 'push');
            app.ChooseButton.Text = 'Choose';
            app.LoggingSettingsPanel = uipanel(app.RunTab);
            app.LoggingSettingsPanel.Title = 'Logging Settings';
            app.MouseIDEditFieldLabel = uilabel(app.LoggingSettingsPanel);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.LoggingSettingsPanel, 'numeric');
            app.WeightEditFieldLabel = uilabel(app.LoggingSettingsPanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.LoggingSettingsPanel, 'numeric');
            app.CameraPanel = uipanel(app.RunTab);
            app.CameraPanel.Title = 'Camera';
            app.PreviewButton = uibutton(app.CameraPanel, 'push');
            app.PreviewButton.Text = 'Preview';
            app.FramerateSpinnerLabel = uilabel(app.CameraPanel);
            app.FramerateSpinnerLabel.Text = 'Framerate';
            app.FramerateSpinner = uispinner(app.CameraPanel);
            app.FPSLabel = uilabel(app.CameraPanel);
            app.FPSLabel.Text = 'FPS';
            app.ExposureSpinnerLabel = uilabel(app.CameraPanel);
            app.ExposureSpinnerLabel.Text = 'Exposure';
            app.ExposureSpinner = uispinner(app.CameraPanel);
            app.AutoCheckBox = uicheckbox(app.CameraPanel);
            app.AutoCheckBox.Text = 'Auto';
            app.ConnectCameraSwitchLabel = uilabel(app.CameraPanel);
            app.ConnectCameraSwitchLabel.Text = 'Connect Camera?';
            app.ConnectCameraSwitch = uiswitch(app.CameraPanel, 'slider');
            app.SaveRecordingSwitchLabel = uilabel(app.CameraPanel);
            app.SaveRecordingSwitchLabel.Text = 'Save Recording?';
            app.SaveRecordingSwitch = uiswitch(app.CameraPanel, 'slider');
            app.ResetButton = uibutton(app.CameraPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.TimingssPanel = uipanel(app.ParametersTab);
            app.TimingssPanel.Title = 'Timings (s)';
            app.ITIminEditFieldLabel = uilabel(app.TimingssPanel);
            app.ITIminEditFieldLabel.Text = 'ITI (min)';
            app.ITIminEditField = uieditfield(app.TimingssPanel, 'numeric');
            app.ITImaxEditFieldLabel = uilabel(app.TimingssPanel);
            app.ITImaxEditFieldLabel.Text = 'ITI (max)';
            app.ITImaxEditField = uieditfield(app.TimingssPanel, 'numeric');
            app.NLPEditFieldLabel = uilabel(app.TimingssPanel);
            app.NLPEditFieldLabel.Text = 'NLP';
            app.NLPEditField = uieditfield(app.TimingssPanel, 'numeric');
            app.TRWEditFieldLabel = uilabel(app.TimingssPanel);
            app.TRWEditFieldLabel.Text = 'TRW';
            app.TRWEditField = uieditfield(app.TimingssPanel, 'numeric');
            app.TOEditFieldLabel = uilabel(app.TimingssPanel);
            app.TOEditFieldLabel.Text = 'TO';
            app.TOEditField = uieditfield(app.TimingssPanel, 'numeric');
            app.ProbabilitiesPanel = uipanel(app.ParametersTab);
            app.ProbabilitiesPanel.Title = 'Probabilities (%)';
            app.GoChancesEditFieldLabel = uilabel(app.ProbabilitiesPanel);
            app.GoChancesEditFieldLabel.Text = 'Go Chances';
            app.GoChancesEditField = uieditfield(app.ProbabilitiesPanel, 'numeric');
            app.NoGoChancesEditFieldLabel = uilabel(app.ProbabilitiesPanel);
            app.NoGoChancesEditFieldLabel.Text = 'No Go Chances';
            app.NoGoChancesEditField = uieditfield(app.ProbabilitiesPanel, 'numeric');
            app.CatchChancesEditFieldLabel = uilabel(app.ProbabilitiesPanel);
            app.CatchChancesEditFieldLabel.Text = 'Catch Chances';
            app.CatchChancesEditField = uieditfield(app.ProbabilitiesPanel, 'numeric');
            app.ConditionsPanel = uipanel(app.ParametersTab);
            app.ConditionsPanel.Title = 'Conditions';
            app.airpuffSwitchLabel = uilabel(app.ConditionsPanel);
            app.airpuffSwitchLabel.Text = 'airpuff?';
            app.airpuffSwitch = uiswitch(app.ConditionsPanel, 'slider');
            app.airpuffSwitch.ValueChangedFcn = createCallbackFcn(app, @airpuffSwitchValueChanged, true);
            app.CSduringCatchSwitchLabel = uilabel(app.ConditionsPanel);
            app.CSduringCatchSwitchLabel.Text = 'CS+ during Catch?';
            app.CSduringCatchSwitch = uiswitch(app.ConditionsPanel, 'slider');
            app.CSduringCatchSwitch.ValueChangedFcn = createCallbackFcn(app, @CSduringCatchSwitchValueChanged, true);
            app.Lamp = uilamp(app.ConditionsPanel);
            app.Lamp_2 = uilamp(app.ConditionsPanel);
            app.Lamp_3 = uilamp(app.ConditionsPanel);
            app.RewardCRSwitchLabel = uilabel(app.ConditionsPanel);
            app.RewardCRSwitchLabel.Text = 'Reward CR?';
            app.RewardCRSwitch = uiswitch(app.ConditionsPanel, 'slider');
            app.RewardCRSwitch.ValueChangedFcn = createCallbackFcn(app, @RewardCRSwitchValueChanged, true);
            app.Lamp_4 = uilamp(app.ConditionsPanel);
            app.Proto3SwitchLabel = uilabel(app.ConditionsPanel);
            app.Proto3SwitchLabel.Text = 'Proto3?';
            app.Proto3Switch = uiswitch(app.ConditionsPanel, 'slider');
            app.ThresholdsPanel = uipanel(app.ParametersTab);
            app.ThresholdsPanel.Title = 'Thresholds';
            app.lickthresholdEditFieldLabel = uilabel(app.ThresholdsPanel);
            app.lickthresholdEditFieldLabel.Text = 'lick threshold';
            app.lickthresholdEditField = uieditfield(app.ThresholdsPanel, 'numeric');
            app.nogolickthresholdEditFieldLabel = uilabel(app.ThresholdsPanel);
            app.nogolickthresholdEditFieldLabel.Text = 'no go lick threshold';
            app.nogolickthresholdEditField = uieditfield(app.ThresholdsPanel, 'numeric');
            app.SoundsPanel = uipanel(app.ParametersTab);
            app.SoundsPanel.Title = 'Sounds';
            app.PickButton = uibutton(app.SoundsPanel, 'push');
            app.PickButton.Text = 'Pick';
            app.PickButton_2 = uibutton(app.SoundsPanel, 'push');
            app.PickButton_2.Text = 'Pick';
            app.GoSoundLabel = uilabel(app.SoundsPanel);
            app.GoSoundLabel.Text = 'Go Sound';
            app.NoGoSoundLabel = uilabel(app.SoundsPanel);
            app.NoGoSoundLabel.Text = 'No Go Sound';
            app.TrialsPanel = uipanel(app.ParametersTab);
            app.TrialsPanel.Title = 'Trials';
            app.TrialAmountEditFieldLabel = uilabel(app.TrialsPanel);
            app.TrialAmountEditFieldLabel.Text = 'Trial Amount';
            app.TrialAmountEditField = uieditfield(app.TrialsPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gonogoGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end